classdef host_guest_4_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OneguestTab                     matlab.ui.container.Tab
        ComplexedPercentageofGuestEditField  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ComplexConcentrationMEditField  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditFieldLabel  matlab.ui.control.Label
        BindingConstantM1EditField      matlab.ui.control.NumericEditField
        BindingConstantM1EditFieldLabel  matlab.ui.control.Label
        FreeGuestConcentrationMEditField  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditFieldLabel  matlab.ui.control.Label
        GuestConcentrationMEditField    matlab.ui.control.NumericEditField
        GuestConcentrationMEditFieldLabel  matlab.ui.control.Label
        FreeHostConcentrationMEditField  matlab.ui.control.NumericEditField
        FreeHostConcentrationMEditFieldLabel  matlab.ui.control.Label
        HostConcentrationMEditField     matlab.ui.control.NumericEditField
        HostConcentrationMEditFieldLabel  matlab.ui.control.Label
        TwoguestTab                     matlab.ui.container.Tab
        ComplexedGuest1Guest2EditField  matlab.ui.control.NumericEditField
        ComplexedGuest1Guest2EditFieldLabel  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_2  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_2Label  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_3  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_3Label  matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        ComplexConcentrationMEditField_3  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_3Label  matlab.ui.control.Label
        BindingConstantM1EditField_3Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_3  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_3Label  matlab.ui.control.Label
        GuestConcentrationMEditField_3Label  matlab.ui.control.Label
        Guest2Label                     matlab.ui.control.Label
        ComplexConcentrationMEditField_2  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_2Label  matlab.ui.control.Label
        BindingConstantM1EditField_2Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_2  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_2Label  matlab.ui.control.Label
        GuestConcentrationMEditField_2Label  matlab.ui.control.Label
        Guest1Label                     matlab.ui.control.Label
        FreeHostConcentrationMEditField_2  matlab.ui.control.NumericEditField
        FreeHostConcentrationMEditField_2Label  matlab.ui.control.Label
        BindingConstantM1EditField_3    matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_3  matlab.ui.control.NumericEditField
        BindingConstantM1EditField_2    matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_2  matlab.ui.control.NumericEditField
        HostConcentrationMEditField_2   matlab.ui.control.NumericEditField
        HostConcentrationMEditField_2Label  matlab.ui.control.Label
        ThreeguestTab                   matlab.ui.container.Tab
        ComplexedPercentageofGuestEditField_33  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_8Label  matlab.ui.control.Label
        ComplexConcentrationMEditField_33  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_8Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_33  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_8Label  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_31  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_5Label  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_32  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_4Label  matlab.ui.control.Label
        ComplexConcentrationMEditField_32  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_5Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_32  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_5Label  matlab.ui.control.Label
        ComplexConcentrationMEditField_31  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_4Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_31  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_4Label  matlab.ui.control.Label
        FreeHostConcentrationMEditField_3  matlab.ui.control.NumericEditField
        FreeHostConcentrationMEditField_3Label  matlab.ui.control.Label
        BindingConstantM1EditField_32   matlab.ui.control.NumericEditField
        BindingConstantM1EditField_5Label  matlab.ui.control.Label
        GuestConcentrationMEditField_32  matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_5Label  matlab.ui.control.Label
        BindingConstantM1EditField_31   matlab.ui.control.NumericEditField
        BindingConstantM1EditField_4Label  matlab.ui.control.Label
        GuestConcentrationMEditField_31  matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_4Label  matlab.ui.control.Label
        HostConcentrationMEditField_3   matlab.ui.control.NumericEditField
        HostConcentrationMEditField_3Label  matlab.ui.control.Label
        Guest3Label                     matlab.ui.control.Label
        BindingConstantM1EditField_5Label_2  matlab.ui.control.Label
        BindingConstantM1EditField_33   matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_5Label_2  matlab.ui.control.Label
        GuestConcentrationMEditField_33  matlab.ui.control.NumericEditField
        CalculateButton_3               matlab.ui.control.Button
        Guest2Label_2                   matlab.ui.control.Label
        Guest1Label_2                   matlab.ui.control.Label
        FourguestTab                    matlab.ui.container.Tab
        CalculateButton_4               matlab.ui.control.Button
        ComplexConcentrationMEditField_44  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_35Label  matlab.ui.control.Label
        BindingConstantM1EditField_44   matlab.ui.control.NumericEditField
        BindingConstantM1EditField_35Label  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_44  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_34Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_44  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_35Label  matlab.ui.control.Label
        GuestConcentrationMEditField_44  matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_35Label  matlab.ui.control.Label
        Guest4Label                     matlab.ui.control.Label
        ComplexConcentrationMEditField_43  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_34Label  matlab.ui.control.Label
        BindingConstantM1EditField_43   matlab.ui.control.NumericEditField
        BindingConstantM1EditField_34Label  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_43  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_35Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_43  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_34Label  matlab.ui.control.Label
        GuestConcentrationMEditField_43  matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_34Label  matlab.ui.control.Label
        Guest3Label_2                   matlab.ui.control.Label
        ComplexConcentrationMEditField_42  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_7Label  matlab.ui.control.Label
        BindingConstantM1EditField_42   matlab.ui.control.NumericEditField
        BindingConstantM1EditField_7Label  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_42  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_6Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_42  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_7Label  matlab.ui.control.Label
        GuestConcentrationMEditField_42  matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_7Label  matlab.ui.control.Label
        Guest2Label_3                   matlab.ui.control.Label
        ComplexConcentrationMEditField_41  matlab.ui.control.NumericEditField
        ComplexConcentrationMEditField_6Label  matlab.ui.control.Label
        BindingConstantM1EditField_41   matlab.ui.control.NumericEditField
        BindingConstantM1EditField_6Label  matlab.ui.control.Label
        ComplexedPercentageofGuestEditField_41  matlab.ui.control.EditField
        ComplexedPercentageofGuestEditField_7Label  matlab.ui.control.Label
        FreeGuestConcentrationMEditField_41  matlab.ui.control.NumericEditField
        FreeGuestConcentrationMEditField_6Label  matlab.ui.control.Label
        GuestConcentrationMEditField_41  matlab.ui.control.NumericEditField
        GuestConcentrationMEditField_6Label  matlab.ui.control.Label
        Guest1Label_3                   matlab.ui.control.Label
        FreeHostConcentrationMEditField_4  matlab.ui.control.NumericEditField
        FreeHostConcentrationMEditField_4Label  matlab.ui.control.Label
        HostConcentrationMEditField_4   matlab.ui.control.NumericEditField
        HostConcentrationMEditField_4Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %定义已知量
         h0 = app.HostConcentrationMEditField.Value;
            g0 = app.GuestConcentrationMEditField.Value;
            Ka = app.BindingConstantM1EditField.Value;
            

% 定义方程 x(1) = h x(2) = hg x(3) = g 
eq1 = @(h, hg) h + hg - h0;
eq2 = @(hg, g) g + hg - g0;
eq3 = @(h, hg, g) hg - Ka * h*g;

%设置求解精度
functolerance = min(h0,g0)*1e-3;
septtolerance = functolerance*1e-2;
options = optimoptions('fsolve', 'FunctionTolerance', functolerance, 'StepTolerance', septtolerance,'Algorithm', 'levenberg-marquardt');

% 求解方程组
z = fsolve(@(x) [eq1(x(1), x(2)); eq2(x(2), x(3)); eq3(x(1),x(2),x(3))], [h0, g0, g0],options);

app.FreeHostConcentrationMEditField.Value = z(1);
app.FreeGuestConcentrationMEditField.Value = z(3);
app.ComplexConcentrationMEditField.Value = z(2);

%计算百分比
percent = z(2)/g0;
percentValue = sprintf('%.2f%%', percent*100);
app.ComplexedPercentageofGuestEditField.Value = percentValue;

        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
               h0 = app.HostConcentrationMEditField_2.Value;
            g0a = app.GuestConcentrationMEditField_2.Value;
            Kaa = app.BindingConstantM1EditField_2.Value;
            g0b = app.GuestConcentrationMEditField_3.Value;
            Kab = app.BindingConstantM1EditField_3.Value;
           

% 定义方程 x(1) = h; x(2) = hga; x(3) = ga; x(4) = hgb; x(5) = gb 
eq1 = @(h, hga, hgb) h + hga +hgb - h0; %(x(1), x(2), x(4))
eq2 = @(hga, ga) ga + hga - g0a; %(x(2), x(3))
eq3 = @(h, hga, ga) hga - Kaa * h*ga; %(x(1), x(2), x(3))
eq4 = @(hgb, gb) gb+ hgb - g0b; %(x(4), x(5))
eq5 = @(h, hgb, gb) hgb - Kab * h*gb; %(x(1), x(4), x(5))
%设置求解精度
functolerance = min([h0,g0a,g0b])*1e-3;
septtolerance = functolerance*1e-2;
options = optimoptions('fsolve', 'FunctionTolerance', functolerance, 'StepTolerance', septtolerance, 'Algorithm', 'levenberg-marquardt');

% 求解方程组
z = fsolve(@(x) [eq1(x(1), x(2), x(4)); eq2(x(2), x(3)); eq3(x(1),x(2),x(3)); eq4(x(4), x(5)); eq5(x(1),x(4),x(5))], [h0, g0a, g0a,g0b,g0b],options);

app.FreeHostConcentrationMEditField_2.Value = z(1);
app.FreeGuestConcentrationMEditField_2.Value = z(3);
app.ComplexConcentrationMEditField_2.Value = z(2);
app.FreeGuestConcentrationMEditField_3.Value = z(5);
app.ComplexConcentrationMEditField_3.Value = z(4);

%计算百分比

percenta = z(2)/g0a;
percentb = z(4)/g0b;
percentValuea = sprintf('%.2f%%', percenta*100);
percentValueb = sprintf('%.2f%%', percentb*100);
app.ComplexedPercentageofGuestEditField_2.Value = percentValuea;
app.ComplexedPercentageofGuestEditField_3.Value = percentValueb;
g1overg2 = z(2)/z(4);
app.ComplexedGuest1Guest2EditField.Value=g1overg2;
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            h0 = app.HostConcentrationMEditField_3.Value;
            g0a = app.GuestConcentrationMEditField_31.Value;
            Kaa = app.BindingConstantM1EditField_31.Value;
            g0b = app.GuestConcentrationMEditField_32.Value;
            Kab = app.BindingConstantM1EditField_32.Value;
            g0c = app.GuestConcentrationMEditField_33.Value;
            Kac = app.BindingConstantM1EditField_33.Value;

% 定义方程 x(1) = h; x(2) = hga; x(3) = ga; x(4) = hgb; x(5) = gb x(6) = hgc; x(7) = gc             
eq1 = @(h, hga, hgb, hgc) h + hga +hgb +hgc - h0; %(x(1), x(2), x(4), x(6))
eq2 = @(hga, ga) ga + hga - g0a; %(x(2), x(3))
eq3 = @(h, hga, ga) hga - Kaa * h*ga; %(x(1), x(2), x(3))
eq4 = @(hgb, gb) gb+ hgb - g0b; %(x(4), x(5))
eq5 = @(h, hgb, gb) hgb - Kab * h*gb; %(x(1), x(4), x(5))
eq6 = @(hgc, gc) gc+ hgc - g0c; %(x(6), x(7))
eq7 = @(h, hgc, gc) hgc - Kac * h*gc; %(x(1), x(6), x(7))

eqs = @(x) [eq1(x(1), x(2), x(4), x(6)); eq2(x(2), x(3)); eq3(x(1),x(2),x(3)); eq4(x(4), x(5)); eq5(x(1),x(4),x(5)); eq6(x(6), x(7)); eq7(x(1), x(6), x(7))];
%设置求解精度
functolerance = min([h0, g0a, g0b, g0c])*1e-3;
steptolerance = functolerance*1e-2;
options = optimoptions('fsolve', 'FunctionTolerance', functolerance, 'StepTolerance', steptolerance, 'Algorithm', 'levenberg-marquardt');

% 求解方程组

initialvalues = [h0, g0a, g0a, g0b, g0b, g0c, g0c];
z = fsolve(eqs, initialvalues,options);

app.FreeHostConcentrationMEditField_3.Value = z(1);
app.ComplexConcentrationMEditField_31.Value = z(2);
app.FreeGuestConcentrationMEditField_31.Value = z(3);
app.ComplexConcentrationMEditField_32.Value = z(4);
app.FreeGuestConcentrationMEditField_32.Value = z(5);
app.ComplexConcentrationMEditField_33.Value = z(6);
app.FreeGuestConcentrationMEditField_33.Value = z(7);

percenta = z(2)/g0a;
percentb = z(4)/g0b;
percentc = z(6)/g0c;

percentValuea = sprintf('%.2f%%', percenta*100);
percentValueb = sprintf('%.2f%%', percentb*100);
percentValuec = sprintf('%.2f%%', percentc*100);
app.ComplexedPercentageofGuestEditField_31.Value = percentValuea;
app.ComplexedPercentageofGuestEditField_32.Value = percentValueb;
app.ComplexedPercentageofGuestEditField_33.Value = percentValuec;
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            h0 = app.HostConcentrationMEditField_4.Value;
            g0a = app.GuestConcentrationMEditField_41.Value;
            Kaa = app.BindingConstantM1EditField_41.Value;
            g0b = app.GuestConcentrationMEditField_42.Value;
            Kab = app.BindingConstantM1EditField_42.Value;
            g0c = app.GuestConcentrationMEditField_43.Value;
            Kac = app.BindingConstantM1EditField_43.Value;
            g0d = app.GuestConcentrationMEditField_44.Value;
            Kad = app.BindingConstantM1EditField_44.Value;
            % 定义方程 x(1) = h; x(2) = hga; x(3) = ga; x(4) = hgb; x(5) = gb x(6) = hgc; 
% x(7) = gc x(8) = hgd x(9) = gd
eq1 = @(h, hga, hgb, hgc, hgd) h + hga +hgb +hgc +hgd - h0; %(x(1), x(2), x(4), x(6), x(8))
eq2 = @(hga, ga) ga + hga - g0a; %(x(2), x(3))
eq3 = @(h, hga, ga) hga - Kaa * h*ga; %(x(1), x(2), x(3))
eq4 = @(hgb, gb) gb+ hgb - g0b; %(x(4), x(5))
eq5 = @(h, hgb, gb) hgb - Kab * h*gb; %(x(1), x(4), x(5))
eq6 = @(hgc, gc) gc+ hgc - g0c; %(x(6), x(7))
eq7 = @(h, hgc, gc) hgc - Kac * h*gc; %(x(1), x(6), x(7))
eq8 = @(hgd, gd) gd+ hgd - g0d; %(x(8), x(9))
eq9 = @(h, hgd, gd) hgd - Kad * h*gd; %(x(1), x(8), x(9))

eqs = @(x) [eq1(x(1), x(2), x(4), x(6), x(8)); eq2(x(2), x(3)); eq3(x(1),x(2),x(3)); eq4(x(4), x(5)); eq5(x(1),x(4),x(5)); eq6(x(6), x(7)); eq7(x(1), x(6), x(7)); eq8(x(8), x(9)); eq9(x(1), x(8), x(9))];
%设置求解精度
functolerance = min([h0, g0a, g0b, g0c])*1e-3;
steptolerance = functolerance*1e-2;
options = optimoptions('fsolve', 'FunctionTolerance', functolerance, 'StepTolerance', steptolerance, 'Algorithm', 'levenberg-marquardt');

% 求解方程组

initialvalues = [h0, g0a, g0a, g0b, g0b, g0c, g0c, g0d, g0d];
z = fsolve(eqs, initialvalues,options);

app.FreeHostConcentrationMEditField_4.Value = z(1);
app.ComplexConcentrationMEditField_41.Value = z(2);
app.FreeGuestConcentrationMEditField_41.Value = z(3);
app.ComplexConcentrationMEditField_42.Value = z(4);
app.FreeGuestConcentrationMEditField_42.Value = z(5);
app.ComplexConcentrationMEditField_43.Value = z(6);
app.FreeGuestConcentrationMEditField_43.Value = z(7);
app.ComplexConcentrationMEditField_44.Value = z(8);
app.FreeGuestConcentrationMEditField_44.Value = z(9);

percenta = z(2)/g0a;
percentb = z(4)/g0b;
percentc = z(6)/g0c;
percentd = z(8)/g0d;
percentValuea = sprintf('%.2f%%', percenta*100);
percentValueb = sprintf('%.2f%%', percentb*100);
percentValuec = sprintf('%.2f%%', percentc*100);
percentValued = sprintf('%.2f%%', percentd*100);
app.ComplexedPercentageofGuestEditField_41.Value = percentValuea;
app.ComplexedPercentageofGuestEditField_42.Value = percentValueb;
app.ComplexedPercentageofGuestEditField_43.Value = percentValuec;
app.ComplexedPercentageofGuestEditField_44.Value = percentValued;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OneguestTab = uitab(app.TabGroup);
            app.OneguestTab.Title = 'One guest';
            app.HostConcentrationMEditFieldLabel = uilabel(app.OneguestTab);
            app.HostConcentrationMEditFieldLabel.Text = 'Host Concentration (M)';
            app.HostConcentrationMEditField = uieditfield(app.OneguestTab, 'numeric');
            app.HostConcentrationMEditField.Limits = [0 Inf];
            app.HostConcentrationMEditField.Value = 1;
            app.FreeHostConcentrationMEditFieldLabel = uilabel(app.OneguestTab);
            app.FreeHostConcentrationMEditFieldLabel.Text = 'Free Host Concentration (M)';
            app.FreeHostConcentrationMEditField = uieditfield(app.OneguestTab, 'numeric');
            app.GuestConcentrationMEditFieldLabel = uilabel(app.OneguestTab);
            app.GuestConcentrationMEditFieldLabel.Text = 'Guest Concentration (M)';
            app.GuestConcentrationMEditField = uieditfield(app.OneguestTab, 'numeric');
            app.GuestConcentrationMEditField.Limits = [0 Inf];
            app.GuestConcentrationMEditField.Value = 1;
            app.FreeGuestConcentrationMEditFieldLabel = uilabel(app.OneguestTab);
            app.FreeGuestConcentrationMEditFieldLabel.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField = uieditfield(app.OneguestTab, 'numeric');
            app.BindingConstantM1EditFieldLabel = uilabel(app.OneguestTab);
            app.BindingConstantM1EditFieldLabel.Text = 'Binding Constant ( M^-1)';
            app.BindingConstantM1EditField = uieditfield(app.OneguestTab, 'numeric');
            app.BindingConstantM1EditField.Limits = [0 Inf];
            app.BindingConstantM1EditField.Value = 1;
            app.ComplexConcentrationMEditFieldLabel = uilabel(app.OneguestTab);
            app.ComplexConcentrationMEditFieldLabel.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField = uieditfield(app.OneguestTab, 'numeric');
            app.CalculateButton = uibutton(app.OneguestTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ComplexedPercentageofGuestEditFieldLabel = uilabel(app.OneguestTab);
            app.ComplexedPercentageofGuestEditFieldLabel.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField = uieditfield(app.OneguestTab, 'text');
            app.TwoguestTab = uitab(app.TabGroup);
            app.TwoguestTab.Title = 'Two guest';
            app.HostConcentrationMEditField_2Label = uilabel(app.TwoguestTab);
            app.HostConcentrationMEditField_2Label.Text = 'Host Concentration (M)';
            app.HostConcentrationMEditField_2 = uieditfield(app.TwoguestTab, 'numeric');
            app.HostConcentrationMEditField_2.Limits = [0 Inf];
            app.HostConcentrationMEditField_2.Value = 1;
            app.GuestConcentrationMEditField_2 = uieditfield(app.TwoguestTab, 'numeric');
            app.GuestConcentrationMEditField_2.Limits = [0 Inf];
            app.GuestConcentrationMEditField_2.Value = 1;
            app.BindingConstantM1EditField_2 = uieditfield(app.TwoguestTab, 'numeric');
            app.BindingConstantM1EditField_2.Limits = [0 Inf];
            app.BindingConstantM1EditField_2.Value = 1;
            app.GuestConcentrationMEditField_3 = uieditfield(app.TwoguestTab, 'numeric');
            app.GuestConcentrationMEditField_3.Limits = [0 Inf];
            app.GuestConcentrationMEditField_3.Value = 1;
            app.BindingConstantM1EditField_3 = uieditfield(app.TwoguestTab, 'numeric');
            app.BindingConstantM1EditField_3.Limits = [0 Inf];
            app.BindingConstantM1EditField_3.Value = 1;
            app.FreeHostConcentrationMEditField_2Label = uilabel(app.TwoguestTab);
            app.FreeHostConcentrationMEditField_2Label.Text = 'Free Host Concentration (M)';
            app.FreeHostConcentrationMEditField_2 = uieditfield(app.TwoguestTab, 'numeric');
            app.Guest1Label = uilabel(app.TwoguestTab);
            app.Guest1Label.Text = 'Guest1';
            app.GuestConcentrationMEditField_2Label = uilabel(app.TwoguestTab);
            app.GuestConcentrationMEditField_2Label.Text = 'Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_2Label = uilabel(app.TwoguestTab);
            app.FreeGuestConcentrationMEditField_2Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_2 = uieditfield(app.TwoguestTab, 'numeric');
            app.BindingConstantM1EditField_2Label = uilabel(app.TwoguestTab);
            app.BindingConstantM1EditField_2Label.Text = 'Binding Constant ( M^-1)';
            app.ComplexConcentrationMEditField_2Label = uilabel(app.TwoguestTab);
            app.ComplexConcentrationMEditField_2Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_2 = uieditfield(app.TwoguestTab, 'numeric');
            app.Guest2Label = uilabel(app.TwoguestTab);
            app.Guest2Label.Text = 'Guest2';
            app.GuestConcentrationMEditField_3Label = uilabel(app.TwoguestTab);
            app.GuestConcentrationMEditField_3Label.Text = 'Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_3Label = uilabel(app.TwoguestTab);
            app.FreeGuestConcentrationMEditField_3Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_3 = uieditfield(app.TwoguestTab, 'numeric');
            app.BindingConstantM1EditField_3Label = uilabel(app.TwoguestTab);
            app.BindingConstantM1EditField_3Label.Text = 'Binding Constant ( M^-1)';
            app.ComplexConcentrationMEditField_3Label = uilabel(app.TwoguestTab);
            app.ComplexConcentrationMEditField_3Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_3 = uieditfield(app.TwoguestTab, 'numeric');
            app.CalculateButton_2 = uibutton(app.TwoguestTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ComplexedPercentageofGuestEditField_3Label = uilabel(app.TwoguestTab);
            app.ComplexedPercentageofGuestEditField_3Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_3 = uieditfield(app.TwoguestTab, 'text');
            app.ComplexedPercentageofGuestEditField_2Label = uilabel(app.TwoguestTab);
            app.ComplexedPercentageofGuestEditField_2Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_2 = uieditfield(app.TwoguestTab, 'text');
            app.ComplexedGuest1Guest2EditFieldLabel = uilabel(app.TwoguestTab);
            app.ComplexedGuest1Guest2EditFieldLabel.Text = 'Complexed  Guest1 : Guest2  ';
            app.ComplexedGuest1Guest2EditField = uieditfield(app.TwoguestTab, 'numeric');
            app.ThreeguestTab = uitab(app.TabGroup);
            app.ThreeguestTab.Title = 'Three guest';
            app.Guest1Label_2 = uilabel(app.ThreeguestTab);
            app.Guest1Label_2.Text = 'Guest1';
            app.Guest2Label_2 = uilabel(app.ThreeguestTab);
            app.Guest2Label_2.Text = 'Guest2';
            app.CalculateButton_3 = uibutton(app.ThreeguestTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.GuestConcentrationMEditField_33 = uieditfield(app.ThreeguestTab, 'numeric');
            app.GuestConcentrationMEditField_33.Limits = [0 Inf];
            app.GuestConcentrationMEditField_33.Value = 1;
            app.GuestConcentrationMEditField_5Label_2 = uilabel(app.ThreeguestTab);
            app.GuestConcentrationMEditField_5Label_2.Text = 'Guest Concentration (M)';
            app.BindingConstantM1EditField_33 = uieditfield(app.ThreeguestTab, 'numeric');
            app.BindingConstantM1EditField_33.Limits = [0 Inf];
            app.BindingConstantM1EditField_33.Value = 1;
            app.BindingConstantM1EditField_5Label_2 = uilabel(app.ThreeguestTab);
            app.BindingConstantM1EditField_5Label_2.Text = 'Binding Constant ( M^-1)';
            app.Guest3Label = uilabel(app.ThreeguestTab);
            app.Guest3Label.Text = 'Guest3';
            app.HostConcentrationMEditField_3Label = uilabel(app.ThreeguestTab);
            app.HostConcentrationMEditField_3Label.Text = 'Host Concentration (M)';
            app.HostConcentrationMEditField_3 = uieditfield(app.ThreeguestTab, 'numeric');
            app.HostConcentrationMEditField_3.Limits = [0 Inf];
            app.HostConcentrationMEditField_3.Value = 1;
            app.GuestConcentrationMEditField_4Label = uilabel(app.ThreeguestTab);
            app.GuestConcentrationMEditField_4Label.Text = 'Guest Concentration (M)';
            app.GuestConcentrationMEditField_31 = uieditfield(app.ThreeguestTab, 'numeric');
            app.GuestConcentrationMEditField_31.Limits = [0 Inf];
            app.GuestConcentrationMEditField_31.Value = 1;
            app.BindingConstantM1EditField_4Label = uilabel(app.ThreeguestTab);
            app.BindingConstantM1EditField_4Label.Text = 'Binding Constant ( M^-1)';
            app.BindingConstantM1EditField_31 = uieditfield(app.ThreeguestTab, 'numeric');
            app.BindingConstantM1EditField_31.Limits = [0 Inf];
            app.BindingConstantM1EditField_31.Value = 1;
            app.GuestConcentrationMEditField_5Label = uilabel(app.ThreeguestTab);
            app.GuestConcentrationMEditField_5Label.Text = 'Guest Concentration (M)';
            app.GuestConcentrationMEditField_32 = uieditfield(app.ThreeguestTab, 'numeric');
            app.GuestConcentrationMEditField_32.Limits = [0 Inf];
            app.GuestConcentrationMEditField_32.Value = 1;
            app.BindingConstantM1EditField_5Label = uilabel(app.ThreeguestTab);
            app.BindingConstantM1EditField_5Label.Text = 'Binding Constant ( M^-1)';
            app.BindingConstantM1EditField_32 = uieditfield(app.ThreeguestTab, 'numeric');
            app.BindingConstantM1EditField_32.Limits = [0 Inf];
            app.BindingConstantM1EditField_32.Value = 1;
            app.FreeHostConcentrationMEditField_3Label = uilabel(app.ThreeguestTab);
            app.FreeHostConcentrationMEditField_3Label.Text = 'Free Host Concentration (M)';
            app.FreeHostConcentrationMEditField_3 = uieditfield(app.ThreeguestTab, 'numeric');
            app.FreeGuestConcentrationMEditField_4Label = uilabel(app.ThreeguestTab);
            app.FreeGuestConcentrationMEditField_4Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_31 = uieditfield(app.ThreeguestTab, 'numeric');
            app.ComplexConcentrationMEditField_4Label = uilabel(app.ThreeguestTab);
            app.ComplexConcentrationMEditField_4Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_31 = uieditfield(app.ThreeguestTab, 'numeric');
            app.FreeGuestConcentrationMEditField_5Label = uilabel(app.ThreeguestTab);
            app.FreeGuestConcentrationMEditField_5Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_32 = uieditfield(app.ThreeguestTab, 'numeric');
            app.ComplexConcentrationMEditField_5Label = uilabel(app.ThreeguestTab);
            app.ComplexConcentrationMEditField_5Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_32 = uieditfield(app.ThreeguestTab, 'numeric');
            app.ComplexedPercentageofGuestEditField_4Label = uilabel(app.ThreeguestTab);
            app.ComplexedPercentageofGuestEditField_4Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_32 = uieditfield(app.ThreeguestTab, 'text');
            app.ComplexedPercentageofGuestEditField_5Label = uilabel(app.ThreeguestTab);
            app.ComplexedPercentageofGuestEditField_5Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_31 = uieditfield(app.ThreeguestTab, 'text');
            app.FreeGuestConcentrationMEditField_8Label = uilabel(app.ThreeguestTab);
            app.FreeGuestConcentrationMEditField_8Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_33 = uieditfield(app.ThreeguestTab, 'numeric');
            app.ComplexConcentrationMEditField_8Label = uilabel(app.ThreeguestTab);
            app.ComplexConcentrationMEditField_8Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_33 = uieditfield(app.ThreeguestTab, 'numeric');
            app.ComplexedPercentageofGuestEditField_8Label = uilabel(app.ThreeguestTab);
            app.ComplexedPercentageofGuestEditField_8Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_33 = uieditfield(app.ThreeguestTab, 'text');
            app.FourguestTab = uitab(app.TabGroup);
            app.FourguestTab.Title = 'Four guest';
            app.HostConcentrationMEditField_4Label = uilabel(app.FourguestTab);
            app.HostConcentrationMEditField_4Label.Text = 'Host Concentration (M)';
            app.HostConcentrationMEditField_4 = uieditfield(app.FourguestTab, 'numeric');
            app.HostConcentrationMEditField_4.Limits = [0 Inf];
            app.HostConcentrationMEditField_4.Value = 1;
            app.FreeHostConcentrationMEditField_4Label = uilabel(app.FourguestTab);
            app.FreeHostConcentrationMEditField_4Label.Text = 'Free Host Concentration (M)';
            app.FreeHostConcentrationMEditField_4 = uieditfield(app.FourguestTab, 'numeric');
            app.Guest1Label_3 = uilabel(app.FourguestTab);
            app.Guest1Label_3.Text = 'Guest1';
            app.GuestConcentrationMEditField_6Label = uilabel(app.FourguestTab);
            app.GuestConcentrationMEditField_6Label.Text = 'Guest Concentration (M)';
            app.GuestConcentrationMEditField_41 = uieditfield(app.FourguestTab, 'numeric');
            app.GuestConcentrationMEditField_41.Limits = [0 Inf];
            app.GuestConcentrationMEditField_41.Value = 1;
            app.FreeGuestConcentrationMEditField_6Label = uilabel(app.FourguestTab);
            app.FreeGuestConcentrationMEditField_6Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_41 = uieditfield(app.FourguestTab, 'numeric');
            app.ComplexedPercentageofGuestEditField_7Label = uilabel(app.FourguestTab);
            app.ComplexedPercentageofGuestEditField_7Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_41 = uieditfield(app.FourguestTab, 'text');
            app.BindingConstantM1EditField_6Label = uilabel(app.FourguestTab);
            app.BindingConstantM1EditField_6Label.Text = 'Binding Constant ( M^-1)';
            app.BindingConstantM1EditField_41 = uieditfield(app.FourguestTab, 'numeric');
            app.BindingConstantM1EditField_41.Limits = [0 Inf];
            app.BindingConstantM1EditField_41.Value = 1;
            app.ComplexConcentrationMEditField_6Label = uilabel(app.FourguestTab);
            app.ComplexConcentrationMEditField_6Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_41 = uieditfield(app.FourguestTab, 'numeric');
            app.Guest2Label_3 = uilabel(app.FourguestTab);
            app.Guest2Label_3.Text = 'Guest2';
            app.GuestConcentrationMEditField_7Label = uilabel(app.FourguestTab);
            app.GuestConcentrationMEditField_7Label.Text = 'Guest Concentration (M)';
            app.GuestConcentrationMEditField_42 = uieditfield(app.FourguestTab, 'numeric');
            app.GuestConcentrationMEditField_42.Limits = [0 Inf];
            app.GuestConcentrationMEditField_42.Value = 1;
            app.FreeGuestConcentrationMEditField_7Label = uilabel(app.FourguestTab);
            app.FreeGuestConcentrationMEditField_7Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_42 = uieditfield(app.FourguestTab, 'numeric');
            app.ComplexedPercentageofGuestEditField_6Label = uilabel(app.FourguestTab);
            app.ComplexedPercentageofGuestEditField_6Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_42 = uieditfield(app.FourguestTab, 'text');
            app.BindingConstantM1EditField_7Label = uilabel(app.FourguestTab);
            app.BindingConstantM1EditField_7Label.Text = 'Binding Constant ( M^-1)';
            app.BindingConstantM1EditField_42 = uieditfield(app.FourguestTab, 'numeric');
            app.BindingConstantM1EditField_42.Limits = [0 Inf];
            app.BindingConstantM1EditField_42.Value = 1;
            app.ComplexConcentrationMEditField_7Label = uilabel(app.FourguestTab);
            app.ComplexConcentrationMEditField_7Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_42 = uieditfield(app.FourguestTab, 'numeric');
            app.Guest3Label_2 = uilabel(app.FourguestTab);
            app.Guest3Label_2.Text = 'Guest3';
            app.GuestConcentrationMEditField_34Label = uilabel(app.FourguestTab);
            app.GuestConcentrationMEditField_34Label.Text = 'Guest Concentration (M)';
            app.GuestConcentrationMEditField_43 = uieditfield(app.FourguestTab, 'numeric');
            app.GuestConcentrationMEditField_43.Limits = [0 Inf];
            app.GuestConcentrationMEditField_43.Value = 1;
            app.FreeGuestConcentrationMEditField_34Label = uilabel(app.FourguestTab);
            app.FreeGuestConcentrationMEditField_34Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_43 = uieditfield(app.FourguestTab, 'numeric');
            app.ComplexedPercentageofGuestEditField_35Label = uilabel(app.FourguestTab);
            app.ComplexedPercentageofGuestEditField_35Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_43 = uieditfield(app.FourguestTab, 'text');
            app.BindingConstantM1EditField_34Label = uilabel(app.FourguestTab);
            app.BindingConstantM1EditField_34Label.Text = 'Binding Constant ( M^-1)';
            app.BindingConstantM1EditField_43 = uieditfield(app.FourguestTab, 'numeric');
            app.BindingConstantM1EditField_43.Limits = [0 Inf];
            app.BindingConstantM1EditField_43.Value = 1;
            app.ComplexConcentrationMEditField_34Label = uilabel(app.FourguestTab);
            app.ComplexConcentrationMEditField_34Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_43 = uieditfield(app.FourguestTab, 'numeric');
            app.Guest4Label = uilabel(app.FourguestTab);
            app.Guest4Label.Text = 'Guest4';
            app.GuestConcentrationMEditField_35Label = uilabel(app.FourguestTab);
            app.GuestConcentrationMEditField_35Label.Text = 'Guest Concentration (M)';
            app.GuestConcentrationMEditField_44 = uieditfield(app.FourguestTab, 'numeric');
            app.GuestConcentrationMEditField_44.Limits = [0 Inf];
            app.GuestConcentrationMEditField_44.Value = 1;
            app.FreeGuestConcentrationMEditField_35Label = uilabel(app.FourguestTab);
            app.FreeGuestConcentrationMEditField_35Label.Text = 'Free Guest Concentration (M)';
            app.FreeGuestConcentrationMEditField_44 = uieditfield(app.FourguestTab, 'numeric');
            app.ComplexedPercentageofGuestEditField_34Label = uilabel(app.FourguestTab);
            app.ComplexedPercentageofGuestEditField_34Label.Text = 'Complexed Percentage of Guest';
            app.ComplexedPercentageofGuestEditField_44 = uieditfield(app.FourguestTab, 'text');
            app.BindingConstantM1EditField_35Label = uilabel(app.FourguestTab);
            app.BindingConstantM1EditField_35Label.Text = 'Binding Constant ( M^-1)';
            app.BindingConstantM1EditField_44 = uieditfield(app.FourguestTab, 'numeric');
            app.BindingConstantM1EditField_44.Limits = [0 Inf];
            app.BindingConstantM1EditField_44.Value = 1;
            app.ComplexConcentrationMEditField_35Label = uilabel(app.FourguestTab);
            app.ComplexConcentrationMEditField_35Label.Text = 'Complex Concentration (M)';
            app.ComplexConcentrationMEditField_44 = uieditfield(app.FourguestTab, 'numeric');
            app.CalculateButton_4 = uibutton(app.FourguestTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = host_guest_4_01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end